classdef GUI_Artifind < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArtifindUIFigure               matlab.ui.Figure
        loadEEGButton                  matlab.ui.control.Button
        RunButton                      matlab.ui.control.StateButton
        CardiobalisticArtifactDetectCheckBox  matlab.ui.control.CheckBox
        GradientSwitchArtifactDetectCheckBox  matlab.ui.control.CheckBox
        IfpresentECGchannelnumberEditField  matlab.ui.control.NumericEditField
        IfpresentECGchannelnumberEditFieldLabel  matlab.ui.control.Label
        SegmentlengthinsecondsEditField  matlab.ui.control.NumericEditField
        SegmentlengthinsecondsEditFieldLabel  matlab.ui.control.Label
        NumberofslicesvolumeEditField  matlab.ui.control.NumericEditField
        NumberofslicesvolumeEditFieldLabel  matlab.ui.control.Label
        NumberofdummyscansEditField    matlab.ui.control.NumericEditField
        NumberofdummyscansEditFieldLabel  matlab.ui.control.Label
        NumberofvolumesEditField       matlab.ui.control.NumericEditField
        NumberofvolumesEditFieldLabel  matlab.ui.control.Label
        TREditField                    matlab.ui.control.NumericEditField
        TREditFieldLabel               matlab.ui.control.Label
        IfpresentECGchannelnumberEditField_2  matlab.ui.control.NumericEditField
        IfpresentECGchannelnumberEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        eeg
        qrs
        EEG_final
        onsets
        trigger_type
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % CardiobalisticArtifactDetectCheckBox, 
        % GradientSwitchArtifactDetectCheckBox, RunButton
        function RunButtonValueChanged(app, event)
            
            % configure the Run Button  = disable the button at the start of
            % the program 
            set(app.RunButton, 'Enable', 'off');
            
            
            % if the GS detect checkbox is checked      
            if app.GradientSwitchArtifactDetectCheckBox.Value == 1 & app.RunButton.Value == 1
                                  
                  number_of_volumes = app.NumberofvolumesEditField.Value;
                  num_slices_per_volume = app.NumberofslicesvolumeEditField.Value ;
                  TR = app.TREditField.Value;
                  ECG_chan_num_GS = app.IfpresentECGchannelnumberEditField_2.Value;
                  dummy_scans = app.NumberofdummyscansEditField.Value;                  
                  
                  % setting default values for the ECG channel number and the
                  % segment length 
                  if ECG_chan_num_GS == 0 | isempty(ECG_chan_num_GS)
                      ECG_chan_num = [] ;
                      app.IfpresentECGchannelnumberEditField_2.ValueDisplayFormat = '%.0f [ ]' ;
                  end
              
                  
                   % applying the GS detect function 
                   [app.EEG_final, app.onsets, app.trigger_type] = GSArtifactDetect(app.eeg.EEG, number_of_volumes, num_slices_per_volume, TR, ECG_chan_num_GS, dummy_scans) ;        
                   disp('GS correction done');
                   msgbox("The Gradient Switch Detect Correction has been successfully completed. Please Select the path and filename for saving the GS corrected EEG.", "Success");
                    
                    % saving the corrected EEG, onsets, trigger_type = The
                    % user determines the path and the file name and then a
                    % new file name get saved with the GS final artifect
                    % corrected matrix 
                   [filename,path] = uigetfile (); 
                   figure(app.ArtifindUIFigure);
                   filename = extractBefore(filename,".mat");
                   new_file_name = strcat(path,filename,'_new_GS_corrected.mat');
                   EEG_final = app.EEG_final;
                   onsets = app.onsets;
                   trigger_type = app.trigger_type;
                   save(new_file_name,'EEG_final', 'onsets', 'trigger_type');
                   disp('GS corrected dataset saved');   
                   msgbox("Saving successfully completed.", "Success");

            
            end 
             
              % if the CB detect checkbox is checked
             if app.CardiobalisticArtifactDetectCheckBox.Value == 1 & app.RunButton.Value == 1
                  
                  ECG_chan_num_CB = app.IfpresentECGchannelnumberEditField.Value;
                  segment_length = app.SegmentlengthinsecondsEditField.Value ;
          
                  % setting default values for the ECG channel number and the
                  % segment length
                  if ECG_chan_num_CB == 0 | isempty(ECG_chan_num_CB)
                      ECG_chan_num = [] ;                       
                  end
                  
                  if segment_length == 0 | isempty(segment_length)
                     segment_length = 20; 
                     
                     % showing the default value in the GUI
                     app.SegmentlengthinsecondsEditField.Enable = 20 ; 
                     app.SegmentlengthinsecondsEditField.Value = segment_length ; 
                     app.SegmentlengthinsecondsEditField.ValueDisplayFormat = '%.0f s' ;
                  end
                  
                  
                  % could be added to the GUI =  from CBCorrect Code
                    %bad_chans = [];
                   %for chan = 1:ECG_chan_num_CB
                        %if abs(max(diff(app.eeg.EEG(chan,:))))>20000 | any(isnan(app.eeg.EEG(chan,:)))
                           % bad_chans(end+1) = chan;
                        %end
                    %end
                   
                    % remove the bad channels 
                    %app.eeg.EEG = pop_select(app.eeg.EEG, 'nochannel', bad_chans);
                    
                     %if signal flipped, i.e. troughs are clear
                     %app.eeg.EEG(64,:) = app.eeg.EEG(64,:)*-1;
                     
                     %displaying 's' for seconds next to the segment length
                     
                     app.SegmentlengthinsecondsEditField.ValueDisplayFormat = '%.0f s' ;
                     
                     % applying the CB detect function 
                     [app.EEG_final, app.qrs] = CBArtifactDetect(app.eeg.EEG, ECG_chan_num_CB, segment_length) ;     
                     app.EEG_final = fmrib_pas(app.EEG_final,app.qrs,'obs',4);
                     disp('CB correction done.');
                     
                     % display to the user a message box for successful
                     % completion of the detection
                     msgbox("The Cardiobalistic Detect Correction has been successfully completed. Please Select the path and filename for saving the CB corrected EEG.", "Success");
                    
                     
                    % saving the corrected EEG and QRS = The
                    % user determines the path and the file name and then a
                    % new file name get saved with the final CB artifect
                    % corrected matrix 
                   [filename,path] = uigetfile (); 
                   figure(app.ArtifindUIFigure);
                   filename = extractBefore(filename,".mat");
                   new_file_name = strcat(path,filename,'_new_CB_corrected.mat');
                   EEG_final = app.EEG_final;
                   qrs = app.qrs;
                   save(new_file_name,'EEG_final', 'qrs');
                   disp('CB corrected dataset saved');     
                   msgbox("Saving successfully completed.", "Success");

             %else
                  %disp('select next option.')
             end 
             
             % configure the Run Button = enable the button at the end of
             % the program 
             set(app.RunButton, 'Enable', 'on');
             

        end

        % Button pushed function: loadEEGButton
        function loadEEGButtonPushed(app, event)
 
              % loading eeglab
              eeglab
              
            % loading the EEG % works 
                [filename,path] = uigetfile ();
                % how can i add a path = do i need to 
                addpath(path); 
                
                figure(app.ArtifindUIFigure);
                app.eeg = load (filename, 'EEG'); 
        end

        % Value changed function: IfpresentECGchannelnumberEditField
        function IfpresentECGchannelnumberEditFieldValueChanged(app, event)
            value = app.IfpresentECGchannelnumberEditField.Value;
            
        end

        % Value changed function: SegmentlengthinsecondsEditField
        function SegmentlengthinsecondsEditFieldValueChanged(app, event)
            value = app.SegmentlengthinsecondsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArtifindUIFigure = uifigure('Visible', 'off');
            app.ArtifindUIFigure.Name = 'Artifind';
            app.IfpresentECGchannelnumberEditField_2Label = uilabel(app.ArtifindUIFigure);
            app.IfpresentECGchannelnumberEditField_2Label.Text = 'If present, ECG channel number';
            app.IfpresentECGchannelnumberEditField_2 = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.TREditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.TREditFieldLabel.Text = 'TR';
            app.TREditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.NumberofvolumesEditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.NumberofvolumesEditFieldLabel.Text = 'Number of volumes';
            app.NumberofvolumesEditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.NumberofdummyscansEditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.NumberofdummyscansEditFieldLabel.Text = 'Number of dummy scans';
            app.NumberofdummyscansEditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.NumberofslicesvolumeEditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.NumberofslicesvolumeEditFieldLabel.Text = 'Number of slices/volume ';
            app.NumberofslicesvolumeEditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.SegmentlengthinsecondsEditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.SegmentlengthinsecondsEditFieldLabel.Text = 'Segment length (in seconds)';
            app.SegmentlengthinsecondsEditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.SegmentlengthinsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentlengthinsecondsEditFieldValueChanged, true);
            app.IfpresentECGchannelnumberEditFieldLabel = uilabel(app.ArtifindUIFigure);
            app.IfpresentECGchannelnumberEditFieldLabel.Text = 'If present, ECG channel number';
            app.IfpresentECGchannelnumberEditField = uieditfield(app.ArtifindUIFigure, 'numeric');
            app.IfpresentECGchannelnumberEditField.ValueChangedFcn = createCallbackFcn(app, @IfpresentECGchannelnumberEditFieldValueChanged, true);
            app.GradientSwitchArtifactDetectCheckBox = uicheckbox(app.ArtifindUIFigure);
            app.GradientSwitchArtifactDetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.GradientSwitchArtifactDetectCheckBox.Text = 'Gradient Switch Artifact Detect';
            app.CardiobalisticArtifactDetectCheckBox = uicheckbox(app.ArtifindUIFigure);
            app.CardiobalisticArtifactDetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.CardiobalisticArtifactDetectCheckBox.Text = 'Cardiobalistic Artifact Detect';
            app.RunButton = uibutton(app.ArtifindUIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.loadEEGButton = uibutton(app.ArtifindUIFigure, 'push');
            app.loadEEGButton.ButtonPushedFcn = createCallbackFcn(app, @loadEEGButtonPushed, true);
            app.loadEEGButton.Text = 'load EEG';
            % Show the figure after all components are created
            app.ArtifindUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Artifind

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArtifindUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArtifindUIFigure)
        end
    end
end